classdef Interpolation_Group02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        PlotButton                      matlab.ui.control.Button
        DegreeEditFieldLabel            matlab.ui.control.Label
        Degree                          matlab.ui.control.NumericEditField
        HowmanyvaluesofxdoyouwanttoseeLabel  matlab.ui.control.Label
        CurveFittingInterpolationLabel  matlab.ui.control.Label
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.EditField
        OutputLabel                     matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        X1                              matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        Y1                              matlab.ui.control.EditField
        xEditFieldLabel                 matlab.ui.control.Label
        xInput                          matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        rst                             matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
          % IDs     : 190205182, 190205183, 190205184, 190205185, 190205186
          % Group   : 02
          % Topic   : Interpolation
          
            X = [str2num(app.X1.Value)];
            Y = [str2num(app.Y1.Value)];
            
            dataX=[];
            dataY=[];
            for i=1:app.Degree.Value
                dataX(i)=[X(i)];
                dataY(i)=[Y(i)];
            end
 
            
            xValues = [str2num(app.xInput.Value)];
            for i=1:length(xValues)
                x(i)=[xValues(i)];
            end
            
            n = length(dataX);
            for k = 1:length(x)
                sum = 0;
                for i = 1:n
                    prod = 1;
                    for j = 1:n
                        if i ~= j
                            prod = prod.*((x(k)-dataX(j))/(dataX(i)-dataX(j)));
                        end
                    end
                    sum = sum + prod.*dataY(i);
                end
                y(k) = sum;
            end
            
            plot(app.UIAxes, dataX,dataY)
            hold (app.UIAxes, 'on')
            plot(app.UIAxes, x,y, 'o')
            ya = num2str(y);
            app.yEditField.Value = ya;
        end

        % Callback function
        function ResetButtonPushed(app, event)
         
        end

        % Button pushed function: rst
        function rstButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'X vs Y')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DegreeEditFieldLabel = uilabel(app.UIFigure);
            app.DegreeEditFieldLabel.Text = 'Degree';
            app.Degree = uieditfield(app.UIFigure, 'numeric');
            app.Degree.Limits = [0 Inf];
            app.Degree.ValueDisplayFormat = '%.0f';
            app.HowmanyvaluesofxdoyouwanttoseeLabel = uilabel(app.UIFigure);
            app.HowmanyvaluesofxdoyouwanttoseeLabel.Text = 'How many values of  x do you want to see?';
            app.CurveFittingInterpolationLabel = uilabel(app.UIFigure);
            app.CurveFittingInterpolationLabel.Text = 'Curve Fitting: Interpolation';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.yEditField.Editable = 'off';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output ';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X1 = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y1 = uieditfield(app.UIFigure, 'text');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xInput = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '------------------------------------';
            app.rst = uibutton(app.UIFigure, 'push');
            app.rst.ButtonPushedFcn = createCallbackFcn(app, @rstButtonPushed, true);
            app.rst.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interpolation_Group02

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end